classdef MultiChannelFigureGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CurrentFolderEditField        matlab.ui.control.EditField
        ChooseFolderButton            matlab.ui.control.Button
        GenerateFigureButton          matlab.ui.control.Button
        FileListListBoxLabel          matlab.ui.control.Label
        FileListListBox               matlab.ui.control.ListBox
        GlobalsettodefaultCheckBox    matlab.ui.control.CheckBox
        HelpButton                    matlab.ui.control.Button
        GeneratesummaryhistogramCheckBox  matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        canvascolorDropDownLabel      matlab.ui.control.Label
        canvascolorDropDown           matlab.ui.control.DropDown
        RightPanel                    matlab.ui.container.Panel
        DAPIMinEditFieldLabel         matlab.ui.control.Label
        DAPIMinEditField              matlab.ui.control.NumericEditField
        DAPIMaxEditFieldLabel         matlab.ui.control.Label
        DAPIMaxEditField              matlab.ui.control.NumericEditField
        DIOMinEditFieldLabel          matlab.ui.control.Label
        DIOMinEditField               matlab.ui.control.NumericEditField
        DIOMaxEditFieldLabel          matlab.ui.control.Label
        DIOMaxEditField               matlab.ui.control.NumericEditField
        DIIMinEditFieldLabel          matlab.ui.control.Label
        DIIMinEditField               matlab.ui.control.NumericEditField
        DIIMaxEditFieldLabel          matlab.ui.control.Label
        DIIMaxEditField               matlab.ui.control.NumericEditField
        UlexMinEditFieldLabel         matlab.ui.control.Label
        ULEXMinEditField              matlab.ui.control.NumericEditField
        UlexMaxLabel                  matlab.ui.control.Label
        ULEXMaxEditField              matlab.ui.control.NumericEditField
        ThresholdLabel                matlab.ui.control.Label
        DAPIThresholdAdjusterSlider   matlab.ui.control.Slider
        BrightnessLabel               matlab.ui.control.Label
        DAPIBrightnessAdjusterSlider  matlab.ui.control.Slider
        ThresholdLabel_2              matlab.ui.control.Label
        DIOThresholdAdjusterSlider    matlab.ui.control.Slider
        BrightnessLabel_2             matlab.ui.control.Label
        DIOBrightnessAdjusterSlider   matlab.ui.control.Slider
        ThresholdLabel_3              matlab.ui.control.Label
        DIIThresholdAdjusterSlider    matlab.ui.control.Slider
        BrightnessLabel_3             matlab.ui.control.Label
        DIIBrightnessAdjusterSlider   matlab.ui.control.Slider
        ThresholdLabel_4              matlab.ui.control.Label
        ULEXThresholdAdjusterSlider   matlab.ui.control.Slider
        BrightnessLabel_4             matlab.ui.control.Label
        ULEXBrightnessAdjusterSlider  matlab.ui.control.Slider
        DAPIresetButton               matlab.ui.control.Button
        DIOresetButton                matlab.ui.control.Button
        DIIresetButton                matlab.ui.control.Button
        ULEXresetButton               matlab.ui.control.Button
        UIAxes_dapi                   matlab.ui.control.UIAxes
        UIAxes_dio                    matlab.ui.control.UIAxes
        UIAxes_dii                    matlab.ui.control.UIAxes
        UIAxes_ulex                   matlab.ui.control.UIAxes
        UIAxes_dapiHist               matlab.ui.control.UIAxes
        UIAxes_dioHist                matlab.ui.control.UIAxes
        UIAxes_diiHist                matlab.ui.control.UIAxes
        UIAxes_ulexHist               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        current_dapi
        current_dio
        current_dii
        current_ulex
        
        UIAxes_dapiHist_LineMin
        UIAxes_dapiHist_LineMax
        
        UIAxes_dioHist_LineMin
        UIAxes_dioHist_LineMax
        
        UIAxes_diiHist_LineMin
        UIAxes_diiHist_LineMax
        
        UIAxes_ulexHist_LineMin
        UIAxes_ulexHist_LineMax
        
        MaxBrightnessAllowed = 5000;
        MaxThresholdAllowed = 2000;
        
    end
    
    methods (Access = private)
        
        function combine(app, cur_ulex, cur_dapi, cur_dio, cur_dii)
            
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Overlaying channels');
            prog.Indeterminate = 'on';
            
            figure; 
            if isequal(app.canvascolorDropDown.Value, 'black') 
                imshow(zeros(size(cur_ulex)));
            else
                imshow(ones(size(cur_ulex)));
            end
            
            ulex = cur_ulex;
            dapi = cur_dapi;
            dio = cur_dio;
            dii = cur_dii;
            
            
            overlay = sortrows(app.UITable.Data,'overlay order');

            for ii = 1:height(overlay)
                
                if overlay(ii,3).('display')
                    chan = overlay.Properties.RowNames{ii};
                else
                    chan = 'br';
                end
                
                color = char(overlay(ii,2).('display color'));
                
                rgbvec = [0 0 0];
                rgbvec3D = ones([size(ulex),3]);
                
                switch(color)
                    case 'red'
                        rgbvec = [1 0 0];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'green'
                        rgbvec = [0 1 0];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'blue'
                        rgbvec = [0 0 1];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'cyan'
                        rgbvec = [0 1 1];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'magenta'
                        rgbvec = [1 0 1];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'yellow'
                        rgbvec = [1 1 0];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'white'
                        rgbvec = [1 1 1];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                    case 'black'
                        rgbvec = [0 0 0];
                        for jj = 1:3
                            rgbvec3D(:,:,jj) = rgbvec3D(:,:,jj)*rgbvec(jj);
                        end
                end
                
                switch(chan)
                    case 'd0'
                        hold on
                        h = imshow(rgbvec3D);
                        hold off
                        dapi(dapi > app.DAPIMaxEditField.Value) = app.DAPIMaxEditField.Value;
                        dapi(dapi < app.DAPIMinEditField.Value) = app.DAPIMinEditField.Value;
                        dapi = dapi - min(dapi(:));
                        dapi = double(dapi);
                        dapi = dapi ./ max(dapi(:));
                        set(h, 'AlphaData', dapi, 'AlphaDataMapping', 'none');
                    case 'd1'
                        hold on
                        i = imshow(rgbvec3D);
                        hold off
                        dio(dio > app.DIOMaxEditField.Value) = app.DIOMaxEditField.Value;
                        dio(dio < app.DIOMinEditField.Value) = app.DIOMinEditField.Value;
                        dio = dio - min(dio(:));
                        dio = double(dio);
                        dio = dio ./ max(dio(:));
                        set(i, 'AlphaData', dio, 'AlphaDataMapping', 'none');
                    case 'd2'
                        hold on
                        j = imshow(rgbvec3D);
                        hold off
                        dii(dii > app.DIIMaxEditField.Value) = app.DIIMaxEditField.Value;
                        dii(dii < app.DIIMinEditField.Value) = app.DIIMinEditField.Value;
                        dii = dii - min(dii(:));
                        dii = double(dii);
                        dii = dii ./ max(dii(:));
                        set(j, 'AlphaData', dii, 'AlphaDataMapping', 'none');
                    case 'd3'
                        hold on
                        h = imshow(rgbvec3D);
                        hold off
                        ulex(ulex > app.ULEXMaxEditField.Value) = app.ULEXMaxEditField.Value;
                        ulex(ulex < app.ULEXMinEditField.Value) = app.ULEXMinEditField.Value;
                        ulex = ulex - min(ulex(:));
                        ulex = double(ulex);
                        ulex = ulex ./ max(ulex(:));
                        set(h, 'AlphaData', ulex, 'AlphaDataMapping', 'none');
                    otherwise
                        
                end
                
            end

            
            if (app.GeneratesummaryhistogramCheckBox.Value == 1)
                figure;
                hold on
                for ii = 1:height(overlay)
                    if overlay(ii,3).('display')
                        chan = overlay.Properties.RowNames{ii};
                    else
                        chan = 'br';
                    end
                    
                    color = char(overlay(ii,2).('display color'));
                    
                    switch(chan)
                        case 'd0'
                            histogram(dapi, 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', color);
                        case 'd1'
                            histogram(dio, 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', color);
                        case 'd2'
                            histogram(dii, 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', color);
                        case 'd3'
                            histogram(ulex, 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', color);
                        otherwise
                    end
                end
                
                hold off

            end
            
            prog.Message = 'Almost done';
            pause(0.2);
            close(prog);
            
        end
        
        function updateTableColor(app)
            for ii = 1:4
                s1 = uistyle("BackgroundColor", char(app.UITable.Data(ii, 2).('display color')));
                addStyle(app.UITable,s1,'row', ii);
                if isequal(char(app.UITable.Data(ii, 2).('display color')), 'blue') || ...
                        isequal(char(app.UITable.Data(ii, 2).('display color')), 'black')
                    s1 = uistyle("FontColor", 'white');
                    addStyle(app.UITable,s1,'row', ii);
                else
                    s1 = uistyle("FontColor", 'black');
                    addStyle(app.UITable,s1,'row', ii);
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FileList = dir('*.TIF');
            [~,idx] = sort([FileList.datenum]);
            FileList = FileList(idx);
            app.FileListListBox.Items = {FileList.name};
            app.CurrentFolderEditField.Value = cd;
            app.DAPIMaxEditFieldLabel.Visible = 'off';
            app.DAPIMinEditFieldLabel.Visible = 'off';
            app.DIOMaxEditFieldLabel.Visible = 'off';
            app.DIOMinEditFieldLabel.Visible = 'off';
            app.DIIMaxEditFieldLabel.Visible = 'off';
            app.DIIMinEditFieldLabel.Visible = 'off';
            app.UlexMaxLabel.Visible = 'off';
            app.UlexMinEditFieldLabel.Visible = 'off';
            
            %Color table initiations
            sz = [4 3];
            varTypes = {'int8','string', 'logical'};
            varNames = {'overlay order','display color', 'display'};
            RowNames = {'d0', 'd1', 'd2', 'd3'};
            t = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames, 'RowNames', RowNames);
            
            t(1,:) = {2, 'blue', true};
            t(2,:) = {3, 'green', true};
            t(3,:) = {4, 'red', true};
            t(4,:) = {1, 'cyan', true};
            
            t.('display color') = categorical(t.('display color'),...
                    {'red','green','blue','cyan', 'yellow', 'magenta', 'white', 'black'},'Ordinal',true);
            
            app.UITable.Data = t;
            
            updateTableColor(app);
            s1 = uistyle('FontWeight','bold', 'HorizontalAlignment', 'center');
            addStyle(app.UITable,s1,'column', 1);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {873, 873};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            selpath = uigetdir;
            cd(selpath)
            app.CurrentFolderEditField.Value = selpath;
            FileList = dir('*.TIF');
            [~,idx] = sort([FileList.datenum]);
            FileList = FileList(idx);
            app.FileListListBox.Items = {FileList.name};
            app.FileListListBox.Value = {};
        end

        % Value changed function: FileListListBox
        function FileListListBoxValueChanged(app, event)
            current_file = app.FileListListBox.Value{1};
            
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Finding corresponding files');
            prog.Indeterminate = 'on';
            
            current_root = current_file(1:end-6);
            
            if isfile([current_root 'd0.TIF'])
                app.current_dapi = imread([current_root 'd0.TIF']);
                app.FileListListBox.Value{1} = [current_root 'd0.TIF'];
                app.UITable.Data(1,3).('display') = 1;
            else
                app.current_dapi = ones(size(imread(current_file))); app.current_dapi(1,1) = 0;
                app.UITable.Data(1,3).('display') = 0;
            end
            
            if isfile([current_root 'd1.TIF'])
                app.current_dio = imread( [current_root 'd1.TIF']);
                app.FileListListBox.Value{2} = [current_root 'd1.TIF'];
                app.UITable.Data(2,3).('display') = 1;
            else
                app.current_dio = ones(size(imread(current_file))); app.current_dio(1,1) = 0;
                app.UITable.Data(2,3).('display') = 0;
            end
                
            if isfile([current_root 'd2.TIF'])    
                app.current_dii = imread( [current_root 'd2.TIF']);
                app.FileListListBox.Value{3} = [current_root 'd2.TIF'];
                app.UITable.Data(3,3).('display') = 1;
            else
                app.current_dii = ones(size(imread(current_file))); app.current_dii(1,1) = 0;
                app.UITable.Data(3,3).('display') = 0;
            end
            
            if isfile([current_root 'd2.TIF']) 
                app.current_ulex = imread([current_root 'd3.TIF']);
                app.FileListListBox.Value{4} = [current_root 'd3.TIF'];
                app.UITable.Data(4,3).('display') = 1;
            else
                app.current_ulex = ones(size(imread(current_file))); app.current_ulex(1,1) = 0;
                app.UITable.Data(4,3).('display') = 0;
            end
            
            prog.Message = 'Setting up d0';
            %% set DAPI
            histogram(app.current_dapi,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_dapiHist);
            if(app.GlobalsettodefaultCheckBox.Value == 1)
                app.DAPIMinEditField.Limits = [0 app.MaxThresholdAllowed]; 
                app.DAPIMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
                app.DAPIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
                app.DAPIBrightnessAdjusterSlider.Limits = [0 app.MaxBrightnessAllowed];
                
                app.DAPIMinEditField.Value = double(min(app.current_dapi(:)));
                app.DAPIMaxEditField.Value = double(max(app.current_dapi(:)));
                app.DAPIThresholdAdjusterSlider.Value = app.DAPIMinEditField.Value;
                app.DAPIBrightnessAdjusterSlider.Value = app.DAPIMaxEditField.Value;
                app.DAPIBrightnessAdjusterSlider.Limits = [app.DAPIMinEditField.Value app.MaxBrightnessAllowed];
                app.DAPIMaxEditField.Limits = app.DAPIBrightnessAdjusterSlider.Limits;
                if (app.DAPIMaxEditField.Value < app.MaxThresholdAllowed) 
                    app.DAPIThresholdAdjusterSlider.Limits = [0 app.DAPIMaxEditField.Value]; 
                end
                app.DAPIMinEditField.Limits = app.DAPIThresholdAdjusterSlider.Limits;
            end
            app.UIAxes_dapiHist_LineMin = xline(app.UIAxes_dapiHist, app.DAPIMinEditField.Value, '--r');
            app.UIAxes_dapiHist_LineMax = xline(app.UIAxes_dapiHist, app.DAPIMaxEditField.Value, '--b');
            imshow(app.current_dapi,[app.DAPIMinEditField.Value app.DAPIMaxEditField.Value],'Parent',app.UIAxes_dapi);
            
            prog.Message = 'Setting up d1';
            %% set DiO
            histogram(app.current_dio,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_dioHist);
            if(app.GlobalsettodefaultCheckBox.Value == 1)
                app.DIOMinEditField.Limits = [0 app.MaxThresholdAllowed]; 
                app.DIOMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
                app.DIOThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
                app.DIOBrightnessAdjusterSlider.Limits = [0 app.MaxBrightnessAllowed];
                
                app.DIOMinEditField.Value = double(min(app.current_dio(:)));
                app.DIOMaxEditField.Value = double(max(app.current_dio(:)));
                app.DIOThresholdAdjusterSlider.Value = app.DIOMinEditField.Value;
                app.DIOBrightnessAdjusterSlider.Value = app.DIOMaxEditField.Value;
                app.DIOBrightnessAdjusterSlider.Limits = [app.DIOMinEditField.Value app.MaxBrightnessAllowed];
                app.DIOMaxEditField.Limits = app.DIOBrightnessAdjusterSlider.Limits;
                if (app.DIOMaxEditField.Value < app.MaxThresholdAllowed) 
                    app.DIOThresholdAdjusterSlider.Limits = [0 app.DIOMaxEditField.Value]; 
                end
                app.DIOMinEditField.Limits = app.DIOThresholdAdjusterSlider.Limits;
            end
            app.UIAxes_dioHist_LineMin = xline(app.UIAxes_dioHist, app.DIOMinEditField.Value, '--r');
            app.UIAxes_dioHist_LineMax = xline(app.UIAxes_dioHist, app.DIOMaxEditField.Value, '--b');
            imshow(app.current_dio,[app.DIOMinEditField.Value app.DIOMaxEditField.Value],'Parent',app.UIAxes_dio);
            
            prog.Message = 'Setting up d2';
            %% set DiI
            histogram(app.current_dii,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_diiHist);
            if(app.GlobalsettodefaultCheckBox.Value == 1)
                app.DIIMinEditField.Limits = [0 app.MaxThresholdAllowed];
                app.DIIMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
                app.DIIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed];
                app.DIIBrightnessAdjusterSlider.Limits = [0 app.MaxBrightnessAllowed];
                
                app.DIIMinEditField.Value = double(min(app.current_dii(:)));
                app.DIIMaxEditField.Value = double(max(app.current_dii(:)));
                app.DIIThresholdAdjusterSlider.Value = app.DIIMinEditField.Value;
                app.DIIBrightnessAdjusterSlider.Value = app.DIIMaxEditField.Value;
                app.DIIBrightnessAdjusterSlider.Limits = [app.DIIMinEditField.Value app.MaxBrightnessAllowed];
                app.DIIMaxEditField.Limits = app.DIIBrightnessAdjusterSlider.Limits;
                if (app.DIIMaxEditField.Value < app.MaxThresholdAllowed) 
                    app.DIIThresholdAdjusterSlider.Limits = [0 app.DIIMaxEditField.Value]; 
                end
                app.DIIMinEditField.Limits = app.DIIThresholdAdjusterSlider.Limits;
            end
            app.UIAxes_diiHist_LineMin = xline(app.UIAxes_diiHist, app.DIIMinEditField.Value, '--r');
            app.UIAxes_diiHist_LineMax = xline(app.UIAxes_diiHist, app.DIIMaxEditField.Value, '--b');
            imshow(app.current_dii,[app.DIIMinEditField.Value app.DIIMaxEditField.Value],'Parent',app.UIAxes_dii);
            
            prog.Message = 'Setting up d3';
            % set ULEX
            histogram(app.current_ulex,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_ulexHist);
            if(app.GlobalsettodefaultCheckBox.Value == 1)
                app.ULEXMinEditField.Limits = [0 app.MaxThresholdAllowed]; 
                app.ULEXMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
                app.ULEXThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
                app.ULEXBrightnessAdjusterSlider.Limits = [0 app.MaxBrightnessAllowed];
                
                app.ULEXMinEditField.Value = double(min(app.current_ulex(:)));
                app.ULEXMaxEditField.Value = double(max(app.current_ulex(:)));
                app.ULEXThresholdAdjusterSlider.Value = app.ULEXMinEditField.Value;
                app.ULEXBrightnessAdjusterSlider.Value = app.ULEXMaxEditField.Value;
                app.ULEXBrightnessAdjusterSlider.Limits = [app.ULEXMinEditField.Value app.MaxBrightnessAllowed];
                app.ULEXMaxEditField.Limits = app.ULEXBrightnessAdjusterSlider.Limits;
                if (app.ULEXMaxEditField.Value < app.MaxThresholdAllowed) 
                    app.ULEXThresholdAdjusterSlider.Limits = [0 app.ULEXMaxEditField.Value]; 
                end
                app.ULEXMinEditField.Limits = app.ULEXThresholdAdjusterSlider.Limits;
            end
            app.UIAxes_ulexHist_LineMin = xline(app.UIAxes_ulexHist, app.ULEXMinEditField.Value, '--r');
            app.UIAxes_ulexHist_LineMax = xline(app.UIAxes_ulexHist, app.ULEXMaxEditField.Value, '--b');
            imshow(app.current_ulex,[app.ULEXMinEditField.Value app.ULEXMaxEditField.Value],'Parent',app.UIAxes_ulex);
            
            app.RightPanel.Enable = 'on';
            app.GenerateFigureButton.Enable = 'on';
            
            prog.Message = 'Almost done';
            pause(0.2);
            close(prog);
                        
        end

        % Button pushed function: GenerateFigureButton
        function GenerateFigureButtonPushed(app, event)
            combine(app, app.current_ulex, app.current_dapi, app.current_dio, app.current_dii);
        end

        % Value changed function: DAPIThresholdAdjusterSlider
        function DAPIThresholdAdjusterSliderValueChanged(app, event)
            threshold_value = app.DAPIThresholdAdjusterSlider.Value;
            app.DAPIMinEditField.Value = threshold_value;
            max_value = app.DAPIMaxEditField.Value;
            app.DAPIBrightnessAdjusterSlider.Limits = [app.DAPIMinEditField.Value app.MaxBrightnessAllowed];
            app.DAPIMaxEditField.Limits = app.DAPIBrightnessAdjusterSlider.Limits;
            imshow(app.current_dapi,[threshold_value max_value],'Parent',app.UIAxes_dapi);
            delete(app.UIAxes_dapiHist_LineMin);
            app.UIAxes_dapiHist_LineMin = xline(app.UIAxes_dapiHist, threshold_value, '--r');
        end

        % Value changed function: DIOThresholdAdjusterSlider
        function DIOThresholdAdjusterSliderValueChanged(app, event)
            threshold_value = app.DIOThresholdAdjusterSlider.Value;
            app.DIOMinEditField.Value = threshold_value;
            max_value = app.DIOMaxEditField.Value;
            app.DIOBrightnessAdjusterSlider.Limits = [app.DIOMinEditField.Value app.MaxBrightnessAllowed];
            app.DIOMaxEditField.Limits = app.DIOBrightnessAdjusterSlider.Limits;
            imshow(app.current_dio,[threshold_value max_value],'Parent',app.UIAxes_dio);
            delete(app.UIAxes_dioHist_LineMin);
            app.UIAxes_dioHist_LineMin = xline(app.UIAxes_dioHist, threshold_value, '--r');
        end

        % Value changed function: DIIThresholdAdjusterSlider
        function DIIThresholdAdjusterSliderValueChanged(app, event)
            threshold_value = app.DIIThresholdAdjusterSlider.Value;
            app.DIIMinEditField.Value = threshold_value;
            max_value = app.DIIMaxEditField.Value;
            app.DIIBrightnessAdjusterSlider.Limits = [app.DIIMinEditField.Value app.MaxBrightnessAllowed];
            app.DIIMaxEditField.Limits = app.DIIBrightnessAdjusterSlider.Limits;
            imshow(app.current_dii,[threshold_value max_value],'Parent',app.UIAxes_dii);
            delete(app.UIAxes_diiHist_LineMin);
            app.UIAxes_diiHist_LineMin = xline(app.UIAxes_diiHist, threshold_value, '--r');
            
        end

        % Value changed function: ULEXThresholdAdjusterSlider
        function ULEXThresholdAdjusterSliderValueChanged(app, event)
            threshold_value = app.ULEXThresholdAdjusterSlider.Value;
            app.ULEXMinEditField.Value = threshold_value;
            max_value = app.ULEXMaxEditField.Value;
            app.ULEXBrightnessAdjusterSlider.Limits = [app.ULEXMinEditField.Value app.MaxBrightnessAllowed];
            app.ULEXMaxEditField.Limits = app.ULEXBrightnessAdjusterSlider.Limits;
            imshow(app.current_ulex,[threshold_value max_value],'Parent',app.UIAxes_ulex);
            delete(app.UIAxes_ulexHist_LineMin);
            app.UIAxes_ulexHist_LineMin = xline(app.UIAxes_ulexHist, threshold_value, '--r');
            
        end

        % Value changed function: DAPIBrightnessAdjusterSlider
        function DAPIBrightnessAdjusterSliderValueChanged(app, event)
            max_value = app.DAPIBrightnessAdjusterSlider.Value;
            app.DAPIMaxEditField.Value = max_value;
            threshold_value = app.DAPIMinEditField.Value;
            if (max_value < app.MaxThresholdAllowed) 
                app.DAPIThresholdAdjusterSlider.Limits = [0 max_value]; 
            else
                app.DAPIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed];
            end
            app.DAPIMinEditField.Limits = app.DAPIThresholdAdjusterSlider.Limits;
            imshow(app.current_dapi,[threshold_value max_value],'Parent',app.UIAxes_dapi);
            delete(app.UIAxes_dapiHist_LineMax);
            app.UIAxes_dapiHist_LineMax = xline(app.UIAxes_dapiHist, max_value, '--b');
        end

        % Value changed function: DIOBrightnessAdjusterSlider
        function DIOBrightnessAdjusterSliderValueChanged(app, event)
            max_value = app.DIOBrightnessAdjusterSlider.Value;
            app.DIOMaxEditField.Value = max_value;
            threshold_value = app.DIOMinEditField.Value;
            if (max_value < app.MaxThresholdAllowed) 
                app.DIOThresholdAdjusterSlider.Limits = [0 max_value]; 
            else
                app.DIOThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed];
            end
            app.DIOMinEditField.Limits = app.DIOThresholdAdjusterSlider.Limits;
            imshow(app.current_dio,[threshold_value max_value],'Parent',app.UIAxes_dio);
            delete(app.UIAxes_dioHist_LineMax);
            app.UIAxes_dioHist_LineMax = xline(app.UIAxes_dioHist, max_value, '--b');
        end

        % Value changed function: DIIBrightnessAdjusterSlider
        function DIIBrightnessAdjusterSliderValueChanged(app, event)
            max_value = app.DIIBrightnessAdjusterSlider.Value;
            app.DIIMaxEditField.Value = max_value;
            threshold_value = app.DIIMinEditField.Value;
            if (max_value < app.MaxThresholdAllowed) 
                app.DIIThresholdAdjusterSlider.Limits = [0 max_value]; 
            else
                app.DIIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed];
            end
            app.DIIMinEditField.Limits = app.DIIThresholdAdjusterSlider.Limits;
            imshow(app.current_dii,[threshold_value max_value],'Parent',app.UIAxes_dii);
            delete(app.UIAxes_diiHist_LineMax);
            app.UIAxes_diiHist_LineMax = xline(app.UIAxes_diiHist, max_value, '--b');
            
        end

        % Value changed function: ULEXBrightnessAdjusterSlider
        function ULEXBrightnessAdjusterSliderValueChanged(app, event)
            max_value = app.ULEXBrightnessAdjusterSlider.Value;
            app.ULEXMaxEditField.Value = max_value;
            threshold_value = app.ULEXMinEditField.Value;
            if (max_value < app.MaxThresholdAllowed) 
                app.ULEXThresholdAdjusterSlider.Limits = [0 max_value]; 
            else
                app.ULEXThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed];
            end
            app.ULEXMinEditField.Limits = app.ULEXThresholdAdjusterSlider.Limits;
            imshow(app.current_ulex,[threshold_value max_value],'Parent',app.UIAxes_ulex);
            delete(app.UIAxes_ulexHist_LineMax);
            app.UIAxes_ulexHist_LineMax = xline(app.UIAxes_ulexHist, max_value, '--b');
            
        end

        % Value changed function: DAPIMinEditField
        function DAPIMinEditFieldValueChanged(app, event)
            value = app.DAPIMinEditField.Value;
            app.DAPIThresholdAdjusterSlider.Value = value;
            DAPIThresholdAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: DIOMinEditField
        function DIOMinEditFieldValueChanged(app, event)
            value = app.DIOMinEditField.Value;
            app.DIOThresholdAdjusterSlider.Value = value;
            DIOThresholdAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: DIIMinEditField
        function DIIMinEditFieldValueChanged(app, event)
            value = app.DIIMinEditField.Value;
            app.DIIThresholdAdjusterSlider.Value = value;
            DIIThresholdAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: ULEXMinEditField
        function ULEXMinEditFieldValueChanged(app, event)
            value = app.ULEXMinEditField.Value;
            app.ULEXThresholdAdjusterSlider.Value = value;
            ULEXThresholdAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: DAPIMaxEditField
        function DAPIMaxEditFieldValueChanged(app, event)
            value = app.DAPIMaxEditField.Value;
            app.DAPIBrightnessAdjusterSlider.Value = value;
            DAPIBrightnessAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: DIOMaxEditField
        function DIOMaxEditFieldValueChanged(app, event)
            value = app.DIOMaxEditField.Value;
            app.DIOBrightnessAdjusterSlider.Value = value;
            DIOBrightnessAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: DIIMaxEditField
        function DIIMaxEditFieldValueChanged(app, event)
            value = app.DIIMaxEditField.Value;
            app.DIIBrightnessAdjusterSlider.Value = value;
            DIIBrightnessAdjusterSliderValueChanged(app, event);
        end

        % Value changed function: ULEXMaxEditField
        function ULEXMaxEditFieldValueChanged(app, event)
            value = app.ULEXMaxEditField.Value;
            app.ULEXBrightnessAdjusterSlider.Value = value;
            ULEXBrightnessAdjusterSliderValueChanged(app, event);
        end

        % Button pushed function: DAPIresetButton
        function DAPIresetButtonPushed(app, event)
            imshow(app.current_dapi,[],'Parent',app.UIAxes_dapi);
            histogram(app.current_dapi,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_dapiHist);
            
            app.DAPIMinEditField.Limits = [0 app.MaxThresholdAllowed]; app.DAPIMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
            
            app.DAPIMinEditField.Value = double(min(app.current_dapi(:)));
            app.DAPIMaxEditField.Value = double(max(app.current_dapi(:)));
             
            app.DAPIBrightnessAdjusterSlider.Limits = [app.DAPIMinEditField.Value app.MaxBrightnessAllowed];
            app.DAPIMaxEditField.Limits = app.DAPIBrightnessAdjusterSlider.Limits;
            if (app.DAPIMaxEditField.Value < app.MaxThresholdAllowed) 
                app.DAPIThresholdAdjusterSlider.Limits = [0 app.DAPIMaxEditField.Value]; 
            else
                app.DAPIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
            end
            app.DAPIMinEditField.Limits = app.DAPIThresholdAdjusterSlider.Limits;
            
            app.DAPIThresholdAdjusterSlider.Value = app.DAPIMinEditField.Value;
            app.DAPIBrightnessAdjusterSlider.Value = app.DAPIMaxEditField.Value;
            
            delete(app.UIAxes_dapiHist_LineMin);
            delete(app.UIAxes_dapiHist_LineMax);
            app.UIAxes_dapiHist_LineMin = xline(app.UIAxes_dapiHist, app.DAPIMinEditField.Value, '--r');
            app.UIAxes_dapiHist_LineMax = xline(app.UIAxes_dapiHist, app.DAPIMaxEditField.Value, '--b');
        end

        % Button pushed function: DIOresetButton
        function DIOresetButtonPushed(app, event)
            imshow(app.current_dio,[],'Parent',app.UIAxes_dio);
            histogram(app.current_dio,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_dioHist);
            
            app.DIOMinEditField.Limits = [0 app.MaxThresholdAllowed]; app.DIOMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
            
            app.DIOMinEditField.Value = double(min(app.current_dio(:)));
            app.DIOMaxEditField.Value = double(max(app.current_dio(:)));
             
            app.DIOBrightnessAdjusterSlider.Limits = [app.DIOMinEditField.Value app.MaxBrightnessAllowed];
            app.DIOMaxEditField.Limits = app.DIOBrightnessAdjusterSlider.Limits;
            if (app.DIOMaxEditField.Value < app.MaxThresholdAllowed) 
                app.DIOThresholdAdjusterSlider.Limits = [0 app.DIOMaxEditField.Value]; 
            else
                app.DIOThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
            end
            app.DIOMinEditField.Limits = app.DIOThresholdAdjusterSlider.Limits;
            
            app.DIOThresholdAdjusterSlider.Value = app.DIOMinEditField.Value;
            app.DIOBrightnessAdjusterSlider.Value = app.DIOMaxEditField.Value;
            
            delete(app.UIAxes_dioHist_LineMin);
            delete(app.UIAxes_dioHist_LineMax);
            app.UIAxes_dioHist_LineMin = xline(app.UIAxes_dioHist, app.DIOMinEditField.Value, '--r');
            app.UIAxes_dioHist_LineMax = xline(app.UIAxes_dioHist, app.DIOMaxEditField.Value, '--b');
        end

        % Button pushed function: DIIresetButton
        function DIIresetButtonPushed(app, event)
            imshow(app.current_dii,[],'Parent',app.UIAxes_dii);
            histogram(app.current_dii,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_diiHist);
            
            app.DIIMinEditField.Limits = [0 app.MaxThresholdAllowed]; app.DIIMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
            
            app.DIIMinEditField.Value = double(min(app.current_dii(:)));
            app.DIIMaxEditField.Value = double(max(app.current_dii(:)));
             
            app.DIIBrightnessAdjusterSlider.Limits = [app.DIIMinEditField.Value app.MaxBrightnessAllowed];
            app.DIIMaxEditField.Limits = app.DIIBrightnessAdjusterSlider.Limits;
            if (app.DIIMaxEditField.Value < app.MaxThresholdAllowed) 
                app.DIIThresholdAdjusterSlider.Limits = [0 app.DIIMaxEditField.Value]; 
            else
                app.DIIThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
            end
            app.DIIMinEditField.Limits = app.DIIThresholdAdjusterSlider.Limits;
            
            app.DIIThresholdAdjusterSlider.Value = app.DIIMinEditField.Value;
            app.DIIBrightnessAdjusterSlider.Value = app.DIIMaxEditField.Value;
            
            delete(app.UIAxes_diiHist_LineMin);
            delete(app.UIAxes_diiHist_LineMax);
            app.UIAxes_diiHist_LineMin = xline(app.UIAxes_diiHist, app.DIIMinEditField.Value, '--r');
            app.UIAxes_diiHist_LineMax = xline(app.UIAxes_diiHist, app.DIIMaxEditField.Value, '--b');
        end

        % Button pushed function: ULEXresetButton
        function ULEXresetButtonPushed(app, event)
            imshow(app.current_ulex,[],'Parent',app.UIAxes_ulex);
            histogram(app.current_ulex,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_ulexHist);
            
            app.ULEXMinEditField.Limits = [0 app.MaxThresholdAllowed]; app.ULEXMaxEditField.Limits = [0 app.MaxBrightnessAllowed];
            
            app.ULEXMinEditField.Value = double(min(app.current_ulex(:)));
            app.ULEXMaxEditField.Value = double(max(app.current_ulex(:)));
             
            app.ULEXBrightnessAdjusterSlider.Limits = [app.ULEXMinEditField.Value app.MaxBrightnessAllowed];
            app.ULEXMaxEditField.Limits = app.ULEXBrightnessAdjusterSlider.Limits;
            if (app.ULEXMaxEditField.Value < app.MaxThresholdAllowed) 
                app.ULEXThresholdAdjusterSlider.Limits = [0 app.ULEXMaxEditField.Value]; 
            else
                app.ULEXThresholdAdjusterSlider.Limits = [0 app.MaxThresholdAllowed]; 
            end
            app.ULEXMinEditField.Limits = app.ULEXThresholdAdjusterSlider.Limits;
            
            app.ULEXThresholdAdjusterSlider.Value = app.ULEXMinEditField.Value;
            app.ULEXBrightnessAdjusterSlider.Value = app.ULEXMaxEditField.Value;
            
            delete(app.UIAxes_ulexHist_LineMin);
            delete(app.UIAxes_ulexHist_LineMax);
            app.UIAxes_ulexHist_LineMin = xline(app.UIAxes_ulexHist, app.ULEXMinEditField.Value, '--r');
            app.UIAxes_ulexHist_LineMax = xline(app.UIAxes_ulexHist, app.ULEXMaxEditField.Value, '--b');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please choose folder containing microscope color channels as d0, d1, d2, and d3');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            updateTableColor(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentFolderEditField.Value = 'C:\';
            app.ChooseFolderButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.GenerateFigureButton = uibutton(app.LeftPanel, 'push');
            app.GenerateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFigureButtonPushed, true);
            app.GenerateFigureButton.Enable = 'off';
            app.GenerateFigureButton.Text = 'Generate Figure';
            app.FileListListBoxLabel = uilabel(app.LeftPanel);
            app.FileListListBoxLabel.Text = 'File List:';
            app.FileListListBox = uilistbox(app.LeftPanel);
            app.FileListListBox.Items = {};
            app.FileListListBox.Multiselect = 'on';
            app.FileListListBox.ValueChangedFcn = createCallbackFcn(app, @FileListListBoxValueChanged, true);
            app.FileListListBox.Value = {};
            app.GlobalsettodefaultCheckBox = uicheckbox(app.LeftPanel);
            app.GlobalsettodefaultCheckBox.Text = 'Set threshold and brightness to default';
            app.GlobalsettodefaultCheckBox.Value = true;
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.GeneratesummaryhistogramCheckBox = uicheckbox(app.LeftPanel);
            app.GeneratesummaryhistogramCheckBox.Text = 'Generate summary histogram';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'overlay order'; 'display color'; 'display'};
            app.UITable.RowName = {'d0'; 'd1'; 'd2'; 'd3'};
            app.UITable.ColumnSortable = [true false false];
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.canvascolorDropDownLabel = uilabel(app.LeftPanel);
            app.canvascolorDropDownLabel.Text = 'canvas color';
            app.canvascolorDropDown = uidropdown(app.LeftPanel);
            app.canvascolorDropDown.Items = {'black', 'white'};
            app.canvascolorDropDown.Value = 'black';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.DAPIMinEditFieldLabel = uilabel(app.RightPanel);
            app.DAPIMinEditFieldLabel.Text = 'DAPI Min';
            app.DAPIMinEditField = uieditfield(app.RightPanel, 'numeric');
            app.DAPIMinEditField.Limits = [0 2000];
            app.DAPIMinEditField.ValueChangedFcn = createCallbackFcn(app, @DAPIMinEditFieldValueChanged, true);
            app.DAPIMaxEditFieldLabel = uilabel(app.RightPanel);
            app.DAPIMaxEditFieldLabel.Text = 'DAPI Max';
            app.DAPIMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.DAPIMaxEditField.Limits = [0 5000];
            app.DAPIMaxEditField.ValueChangedFcn = createCallbackFcn(app, @DAPIMaxEditFieldValueChanged, true);
            app.DAPIMaxEditField.Value = 4095;
            app.DIOMinEditFieldLabel = uilabel(app.RightPanel);
            app.DIOMinEditFieldLabel.Text = 'DIO Min';
            app.DIOMinEditField = uieditfield(app.RightPanel, 'numeric');
            app.DIOMinEditField.Limits = [0 2000];
            app.DIOMinEditField.ValueChangedFcn = createCallbackFcn(app, @DIOMinEditFieldValueChanged, true);
            app.DIOMaxEditFieldLabel = uilabel(app.RightPanel);
            app.DIOMaxEditFieldLabel.Text = 'DIO Max';
            app.DIOMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.DIOMaxEditField.Limits = [0 5000];
            app.DIOMaxEditField.ValueChangedFcn = createCallbackFcn(app, @DIOMaxEditFieldValueChanged, true);
            app.DIOMaxEditField.Value = 4095;
            app.DIIMinEditFieldLabel = uilabel(app.RightPanel);
            app.DIIMinEditFieldLabel.Text = 'DII Min';
            app.DIIMinEditField = uieditfield(app.RightPanel, 'numeric');
            app.DIIMinEditField.Limits = [0 2000];
            app.DIIMinEditField.ValueChangedFcn = createCallbackFcn(app, @DIIMinEditFieldValueChanged, true);
            app.DIIMaxEditFieldLabel = uilabel(app.RightPanel);
            app.DIIMaxEditFieldLabel.Text = 'DII Max';
            app.DIIMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.DIIMaxEditField.Limits = [0 5000];
            app.DIIMaxEditField.ValueChangedFcn = createCallbackFcn(app, @DIIMaxEditFieldValueChanged, true);
            app.DIIMaxEditField.Value = 4095;
            app.UlexMinEditFieldLabel = uilabel(app.RightPanel);
            app.UlexMinEditFieldLabel.Text = 'Ulex Min';
            app.ULEXMinEditField = uieditfield(app.RightPanel, 'numeric');
            app.ULEXMinEditField.Limits = [0 2000];
            app.ULEXMinEditField.ValueChangedFcn = createCallbackFcn(app, @ULEXMinEditFieldValueChanged, true);
            app.UlexMaxLabel = uilabel(app.RightPanel);
            app.UlexMaxLabel.Text = 'Ulex Max';
            app.ULEXMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.ULEXMaxEditField.Limits = [0 5000];
            app.ULEXMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ULEXMaxEditFieldValueChanged, true);
            app.ULEXMaxEditField.Value = 4095;
            app.ThresholdLabel = uilabel(app.RightPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.DAPIThresholdAdjusterSlider = uislider(app.RightPanel);
            app.DAPIThresholdAdjusterSlider.Limits = [0 2000];
            app.DAPIThresholdAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DAPIThresholdAdjusterSliderValueChanged, true);
            app.BrightnessLabel = uilabel(app.RightPanel);
            app.BrightnessLabel.Text = 'Brightness';
            app.DAPIBrightnessAdjusterSlider = uislider(app.RightPanel);
            app.DAPIBrightnessAdjusterSlider.Limits = [0 5000];
            app.DAPIBrightnessAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DAPIBrightnessAdjusterSliderValueChanged, true);
            app.DAPIBrightnessAdjusterSlider.Value = 4095;
            app.ThresholdLabel_2 = uilabel(app.RightPanel);
            app.ThresholdLabel_2.Text = 'Threshold';
            app.DIOThresholdAdjusterSlider = uislider(app.RightPanel);
            app.DIOThresholdAdjusterSlider.Limits = [0 2000];
            app.DIOThresholdAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DIOThresholdAdjusterSliderValueChanged, true);
            app.BrightnessLabel_2 = uilabel(app.RightPanel);
            app.BrightnessLabel_2.Text = 'Brightness';
            app.DIOBrightnessAdjusterSlider = uislider(app.RightPanel);
            app.DIOBrightnessAdjusterSlider.Limits = [0 5000];
            app.DIOBrightnessAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DIOBrightnessAdjusterSliderValueChanged, true);
            app.DIOBrightnessAdjusterSlider.Value = 4095;
            app.ThresholdLabel_3 = uilabel(app.RightPanel);
            app.ThresholdLabel_3.Text = 'Threshold';
            app.DIIThresholdAdjusterSlider = uislider(app.RightPanel);
            app.DIIThresholdAdjusterSlider.Limits = [0 2000];
            app.DIIThresholdAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DIIThresholdAdjusterSliderValueChanged, true);
            app.BrightnessLabel_3 = uilabel(app.RightPanel);
            app.BrightnessLabel_3.Text = 'Brightness';
            app.DIIBrightnessAdjusterSlider = uislider(app.RightPanel);
            app.DIIBrightnessAdjusterSlider.Limits = [0 5000];
            app.DIIBrightnessAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @DIIBrightnessAdjusterSliderValueChanged, true);
            app.DIIBrightnessAdjusterSlider.Value = 4095;
            app.ThresholdLabel_4 = uilabel(app.RightPanel);
            app.ThresholdLabel_4.Text = 'Threshold';
            app.ULEXThresholdAdjusterSlider = uislider(app.RightPanel);
            app.ULEXThresholdAdjusterSlider.Limits = [0 2000];
            app.ULEXThresholdAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @ULEXThresholdAdjusterSliderValueChanged, true);
            app.BrightnessLabel_4 = uilabel(app.RightPanel);
            app.BrightnessLabel_4.Text = 'Brightness';
            app.ULEXBrightnessAdjusterSlider = uislider(app.RightPanel);
            app.ULEXBrightnessAdjusterSlider.Limits = [0 5000];
            app.ULEXBrightnessAdjusterSlider.ValueChangedFcn = createCallbackFcn(app, @ULEXBrightnessAdjusterSliderValueChanged, true);
            app.ULEXBrightnessAdjusterSlider.Value = 4095;
            app.DAPIresetButton = uibutton(app.RightPanel, 'push');
            app.DAPIresetButton.ButtonPushedFcn = createCallbackFcn(app, @DAPIresetButtonPushed, true);
            app.DAPIresetButton.Text = 'reset';
            app.DIOresetButton = uibutton(app.RightPanel, 'push');
            app.DIOresetButton.ButtonPushedFcn = createCallbackFcn(app, @DIOresetButtonPushed, true);
            app.DIOresetButton.Text = 'reset';
            app.DIIresetButton = uibutton(app.RightPanel, 'push');
            app.DIIresetButton.ButtonPushedFcn = createCallbackFcn(app, @DIIresetButtonPushed, true);
            app.DIIresetButton.Text = 'reset';
            app.ULEXresetButton = uibutton(app.RightPanel, 'push');
            app.ULEXresetButton.ButtonPushedFcn = createCallbackFcn(app, @ULEXresetButtonPushed, true);
            app.ULEXresetButton.Text = 'reset';
            app.UIAxes_dapi = uiaxes(app.RightPanel);
            title(app.UIAxes_dapi, 'd0 - DAPI')
            app.UIAxes_dapi.XTick = [];
            app.UIAxes_dapi.YTick = [];
            app.UIAxes_dio = uiaxes(app.RightPanel);
            title(app.UIAxes_dio, 'd1 - DIO')
            app.UIAxes_dio.XTick = [];
            app.UIAxes_dio.YTick = [];
            app.UIAxes_dii = uiaxes(app.RightPanel);
            title(app.UIAxes_dii, 'd2 - DII')
            app.UIAxes_dii.XTick = [];
            app.UIAxes_dii.YTick = [];
            app.UIAxes_ulex = uiaxes(app.RightPanel);
            title(app.UIAxes_ulex, 'd3 - ULEX')
            app.UIAxes_ulex.XTick = [];
            app.UIAxes_ulex.YTick = [];
            app.UIAxes_dapiHist = uiaxes(app.RightPanel);
            title(app.UIAxes_dapiHist, 'Histogram')
            zlabel(app.UIAxes_dapiHist, 'Z')
            app.UIAxes_dioHist = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_dioHist, 'Z')
            app.UIAxes_diiHist = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_diiHist, 'Z')
            app.UIAxes_ulexHist = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_ulexHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiChannelFigureGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end